classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ChooseClasifierDropDown  matlab.ui.control.DropDown
        ChooseClasifierLabel     matlab.ui.control.Label
        NameEditField1           matlab.ui.control.EditField
        NameEditField_3Label     matlab.ui.control.Label
        NameEditField            matlab.ui.control.EditField
        NameEditFieldLabel       matlab.ui.control.Label
        LoadandclassifyButton    matlab.ui.control.Button
        DrawandsaveButton        matlab.ui.control.Button
        DrawandclassifyButton    matlab.ui.control.Button
        Image                    matlab.ui.control.Image
    end

    
    methods (Access = private)
        % Button pushed function: CallFunctionButton
        function DrawAndClassifyPushed(app)
            % Call your custom function here
            option = 1;
            save option.mat option
            mpaper();
        end
        
        function DrawAndSavePushed(app)
            option = 0;
            save option.mat option
            name = strcat(app.NameEditField.Value , ".mat");
            save name.mat name

            if isempty(app.NameEditField.Value)
                % Show a notification if any of the edit text boxes are empty
                uialert(app.UIFigure, 'Please enter the name of the file!', 'Input Error', 'Icon', 'error');
            else
                mpaper();
            end
        end
        
        function LoadAndClassifyPushed(app)

            name = strcat(app.NameEditField1.Value , ".mat");
            save name.mat name
            X = importdata(name);
            data.X = X;
            selectedClassifier = app.ChooseClasifierDropDown.Value;


             if isempty(app.NameEditField1.Value)
                % Show a notification if any of the edit text boxes are empty
                uialert(app.UIFigure, 'Please fill the text box!', 'Input Error', 'Icon', 'error');
            else
                ocr_fun(data , selectedClassifier);
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawandclassifyButton
        function DrawandclassifyButtonPushed(app, event)
            app.DrawAndClassifyPushed();
        end

        % Button pushed function: DrawandsaveButton
        function DrawandsaveButtonPushed(app, event)
            app.DrawAndSavePushed();
        end

        % Button pushed function: LoadandclassifyButton
        function LoadandclassifyButtonPushed(app, event)
            app.LoadAndClassifyPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'plain-gold-xltjzlz7df80i9ai.jpg');
            app.DrawandclassifyButton = uibutton(app.UIFigure, 'push');
            app.DrawandclassifyButton.ButtonPushedFcn = createCallbackFcn(app, @DrawandclassifyButtonPushed, true);
            app.DrawandclassifyButton.Icon = fullfile(pathToMLAPP, 'plain-gold-xltjzlz7df80i9ai.jpg');
            app.DrawandclassifyButton.Text = 'Draw and classify';
            app.DrawandsaveButton = uibutton(app.UIFigure, 'push');
            app.DrawandsaveButton.ButtonPushedFcn = createCallbackFcn(app, @DrawandsaveButtonPushed, true);
            app.DrawandsaveButton.Icon = fullfile(pathToMLAPP, 'plain-gold-xltjzlz7df80i9ai.jpg');
            app.DrawandsaveButton.Text = 'Draw and save';
            app.LoadandclassifyButton = uibutton(app.UIFigure, 'push');
            app.LoadandclassifyButton.ButtonPushedFcn = createCallbackFcn(app, @LoadandclassifyButtonPushed, true);
            app.LoadandclassifyButton.Icon = fullfile(pathToMLAPP, 'plain-gold-xltjzlz7df80i9ai.jpg');
            app.LoadandclassifyButton.Text = 'Load and classify';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField_3Label = uilabel(app.UIFigure);
            app.NameEditField_3Label.Text = 'Name';
            app.NameEditField1 = uieditfield(app.UIFigure, 'text');
            app.ChooseClasifierLabel = uilabel(app.UIFigure);
            app.ChooseClasifierLabel.Text = {'Choose'; 'Clasifier'};
            app.ChooseClasifierDropDown = uidropdown(app.UIFigure);
            app.ChooseClasifierDropDown.Items = {'Classifier 1', 'Classifier 2'};
            app.ChooseClasifierDropDown.Value = 'Classifier 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end